classdef SegmentationApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SegmentationAppUIFigure        matlab.ui.Figure
        PerimeterEditField             matlab.ui.control.NumericEditField
        PerimeterEditField_2Label_2    matlab.ui.control.Label
        OrientationEditField           matlab.ui.control.NumericEditField
        OrientationEditField_2Label_2  matlab.ui.control.Label
        ExtentEditField                matlab.ui.control.NumericEditField
        ExtentEditField_2Label         matlab.ui.control.Label
        EquivDiameterEditField         matlab.ui.control.NumericEditField
        EquivDiameterEditField_2Label  matlab.ui.control.Label
        EulerNumberEditField           matlab.ui.control.NumericEditField
        EulerNumberEditField_2Label    matlab.ui.control.Label
        EccentricityEditField          matlab.ui.control.NumericEditField
        EccentricityEditField_2Label   matlab.ui.control.Label
        SolidityEditField              matlab.ui.control.NumericEditField
        SolidityEditField_2Label_2     matlab.ui.control.Label
        CircularityEditField           matlab.ui.control.NumericEditField
        CircularityEditField_2Label    matlab.ui.control.Label
        AreaEditField                  matlab.ui.control.NumericEditField
        AreaEditField_2Label           matlab.ui.control.Label
        CentroidEditField              matlab.ui.control.EditField
        CentroidEditFieldLabel         matlab.ui.control.Label
        MRIimagesegmentationLabel      matlab.ui.control.Label
        SegmentationButton             matlab.ui.control.Button
        ImportimagefileButton          matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        
    end
    
    properties (Access = public)
        IMG;
        w;
        k;
        x;
        y;
    end
    
    methods (Access = private)
        
    end
    
    methods (Access = public)
        
        function binaryzacja = binaryzacjaFunc(app, td, tg, IMG, w, k)
            IMG_t=zeros(w,k);
            IMG_t(IMG > td) = 1;
            IMG_t(IMG <= td) = 0;
            IMG_t(IMG > tg) = 0;
            binaryzacja = IMG_t;
        end
        function grupowanie = grupowanieFunc(app, IMG_t, m, lgrup, maxiter, x, y)
            [MAP, liczbaregionow] = bwlabel(IMG_t);
            WL = regionprops(MAP, {'Area', 'Eccentricity', 'Solidity'});
            Area = cell2mat({WL. Area});
            Ecc = cell2mat({WL.Eccentricity});
            Solidity = cell2mat({WL.Solidity});
            X = [Area(:) Ecc(:) Solidity(:)];
            [V, U] = fcm(X, lgrup, [m maxiter]);
            W1 = zeros(size(MAP));
            W2 = zeros(size(MAP));
            for i = 1:liczbaregionow
                W1(MAP == i) = U(1, i);
                W2(MAP == i) = U(2, i);
            end
            prog = 0.8;
            W1 = W1 > prog;
            W2 = W2 > prog;
            if W1(y,x) == 1
                W = W1;
            end
            if W2(y,x) == 1
                W = W2;
            end
            grupowanie = W;
        end
        
        function morfologia = morfologiaFunc(app, e_strukt, W)
            W_CO = imopen(imclose(W,e_strukt),e_strukt);
%             W_CO = imdilate(W_CO,e_strukt);
%             W_CO = imerode(W_CO,e_strukt);
%             W_CO = imdilate(W_CO,e_strukt);
            morfologia = W_CO;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: ImportimagefileButton
        function ImportimagefileButtonPushed(app, event)
            app.AreaEditField.Value = 0;
            app.CircularityEditField.Value = 0;
            app.EccentricityEditField.Value = 0;
            app.EulerNumberEditField.Value = 0;
            app.EquivDiameterEditField.Value = 0;
            app.ExtentEditField.Value = 0;
            app.OrientationEditField.Value = 0;
            app.PerimeterEditField.Value = 0;
            app.SolidityEditField.Value = 0;
            app.CentroidEditField.Value = "";
            file = uigetfile('*.*');
            if isequal(file,0)
                
            else
                try
                    IMG_read = dicomread(file);
                    app.IMG = double(IMG_read);
                    [app.w,app.k]=size(app.IMG);
                    app.UIAxes.XLim = [0,app.w];
                    app.UIAxes.YLim = [0,app.k];
                    imshow(app.IMG,[],'Parent',app.UIAxes);
                    app.SegmentationButton.Enable = true;
                catch
                    
                end
            end
        end

        % Close request function: SegmentationAppUIFigure
        function SegmentationAppUIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: SegmentationButton
        function SegmentationButtonPushed(app, event)
            try
                app.SegmentationAppUIFigure.HandleVisibility = 'callback';
                [app.x,app.y] = ginput(1);
                app.SegmentationAppUIFigure.HandleVisibility = 'off';
                app.x = round(app.x);
                app.y = round(app.y);
                plot(app.x,app.y,'.r', 'MarkerSize',15,'Parent',app.UIAxes);
%                 IMG_max=max(max(app.IMG));
%                 tg = IMG_max/1.998;
%                 td = tg/2.22;
                td = 1450;
                tg = 3200;
                IMG_t = binaryzacjaFunc(app, td, tg, app.IMG, app.w, app.k);
                m = 1.1;
                lgrup = 2;
                maxiter = 20;
                W = grupowanieFunc(app, IMG_t, m, lgrup, maxiter, app.x, app.y);
                e_strukt = ones(7,7);
                W_CO = morfologiaFunc(app, e_strukt, W);
                segmented_IMG = app.IMG.*W_CO;
                imshow(segmented_IMG, [],'Parent',app.UIAxes);
                OBSZAR = regionprops(W_CO,'all');
            catch
                
            end
            try
                app.AreaEditField.Value = OBSZAR.Area;
                app.CircularityEditField.Value = OBSZAR.Circularity;
                app.EccentricityEditField.Value = OBSZAR.Eccentricity;
                app.EulerNumberEditField.Value = OBSZAR.EulerNumber;
                app.EquivDiameterEditField.Value = OBSZAR.EquivDiameter;
                app.ExtentEditField.Value = OBSZAR.Extent;
                app.OrientationEditField.Value = OBSZAR.Orientation;
                app.PerimeterEditField.Value = OBSZAR.Perimeter;
                app.SolidityEditField.Value = OBSZAR.Solidity;
                app.CentroidEditField.Value = string(OBSZAR.Centroid(1)) + "; " + string(OBSZAR.Centroid(2));
            catch
                
            end            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SegmentationAppUIFigure = uifigure('Visible', 'off');
            app.SegmentationAppUIFigure.Name = 'Segmentation App';
            app.SegmentationAppUIFigure.CloseRequestFcn = createCallbackFcn(app, @SegmentationAppUIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.SegmentationAppUIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.ImportimagefileButton = uibutton(app.SegmentationAppUIFigure, 'push');
            app.ImportimagefileButton.ButtonPushedFcn = createCallbackFcn(app, @ImportimagefileButtonPushed, true);
            app.ImportimagefileButton.Text = 'Import image file';
            app.SegmentationButton = uibutton(app.SegmentationAppUIFigure, 'push');
            app.SegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentationButtonPushed, true);
            app.SegmentationButton.Enable = 'off';
            app.SegmentationButton.Tooltip = {'After push this button please select segmentation region'};
            app.SegmentationButton.Text = 'Segmentation';
            app.MRIimagesegmentationLabel = uilabel(app.SegmentationAppUIFigure);
            app.MRIimagesegmentationLabel.Text = 'MRI image segmentation';
            app.CentroidEditFieldLabel = uilabel(app.SegmentationAppUIFigure);
            app.CentroidEditFieldLabel.Text = 'Centroid';
            app.CentroidEditField = uieditfield(app.SegmentationAppUIFigure, 'text');
            app.CentroidEditField.Editable = 'off';
            app.AreaEditField_2Label = uilabel(app.SegmentationAppUIFigure);
            app.AreaEditField_2Label.Text = 'Area';
            app.AreaEditField = uieditfield(app.SegmentationAppUIFigure, 'numeric');
            app.AreaEditField.ValueDisplayFormat = '%.0f';
            app.AreaEditField.Editable = 'off';
            app.CircularityEditField_2Label = uilabel(app.SegmentationAppUIFigure);
            app.CircularityEditField_2Label.Text = 'Circularity';
            app.CircularityEditField = uieditfield(app.SegmentationAppUIFigure, 'numeric');
            app.CircularityEditField.ValueDisplayFormat = '%.4f';
            app.CircularityEditField.Editable = 'off';
            app.SolidityEditField_2Label_2 = uilabel(app.SegmentationAppUIFigure);
            app.SolidityEditField_2Label_2.Text = 'Solidity';
            app.SolidityEditField = uieditfield(app.SegmentationAppUIFigure, 'numeric');
            app.SolidityEditField.ValueDisplayFormat = '%.4f';
            app.SolidityEditField.Editable = 'off';
            app.EccentricityEditField_2Label = uilabel(app.SegmentationAppUIFigure);
            app.EccentricityEditField_2Label.Text = 'Eccentricity';
            app.EccentricityEditField = uieditfield(app.SegmentationAppUIFigure, 'numeric');
            app.EccentricityEditField.ValueDisplayFormat = '%.4f';
            app.EccentricityEditField.Editable = 'off';
            app.EulerNumberEditField_2Label = uilabel(app.SegmentationAppUIFigure);
            app.EulerNumberEditField_2Label.Text = 'Euler Number';
            app.EulerNumberEditField = uieditfield(app.SegmentationAppUIFigure, 'numeric');
            app.EulerNumberEditField.ValueDisplayFormat = '%.0f';
            app.EulerNumberEditField.Editable = 'off';
            app.EquivDiameterEditField_2Label = uilabel(app.SegmentationAppUIFigure);
            app.EquivDiameterEditField_2Label.Text = 'Equiv Diameter';
            app.EquivDiameterEditField = uieditfield(app.SegmentationAppUIFigure, 'numeric');
            app.EquivDiameterEditField.ValueDisplayFormat = '%.4f';
            app.EquivDiameterEditField.Editable = 'off';
            app.ExtentEditField_2Label = uilabel(app.SegmentationAppUIFigure);
            app.ExtentEditField_2Label.Text = 'Extent';
            app.ExtentEditField = uieditfield(app.SegmentationAppUIFigure, 'numeric');
            app.ExtentEditField.ValueDisplayFormat = '%.4f';
            app.ExtentEditField.Editable = 'off';
            app.OrientationEditField_2Label_2 = uilabel(app.SegmentationAppUIFigure);
            app.OrientationEditField_2Label_2.Text = 'Orientation';
            app.OrientationEditField = uieditfield(app.SegmentationAppUIFigure, 'numeric');
            app.OrientationEditField.ValueDisplayFormat = '%.4f';
            app.OrientationEditField.Editable = 'off';
            app.PerimeterEditField_2Label_2 = uilabel(app.SegmentationAppUIFigure);
            app.PerimeterEditField_2Label_2.Text = 'Perimeter';
            app.PerimeterEditField = uieditfield(app.SegmentationAppUIFigure, 'numeric');
            app.PerimeterEditField.ValueDisplayFormat = '%.4f';
            app.PerimeterEditField.Editable = 'off';
            % Show the figure after all components are created
            app.SegmentationAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SegmentationApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SegmentationAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SegmentationAppUIFigure)
        end
    end
end